classdef GINSPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        PushTool2              matlab.ui.container.toolbar.PushTool
        PushTool3              matlab.ui.container.toolbar.PushTool
        PushTool4              matlab.ui.container.toolbar.PushTool
        GridLayout             matlab.ui.container.GridLayout
        Panel_4                matlab.ui.container.Panel
        Label_Info             matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        GridLayout5_2          matlab.ui.container.GridLayout
        CheckBox_6             matlab.ui.control.CheckBox
        CheckBox_5             matlab.ui.control.CheckBox
        CheckBox_4             matlab.ui.control.CheckBox
        Button_19              matlab.ui.control.Button
        Button_18              matlab.ui.control.Button
        GridLayout5            matlab.ui.container.GridLayout
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox               matlab.ui.control.CheckBox
        Button_17              matlab.ui.control.Button
        Button_16              matlab.ui.control.Button
        EditField_gins_path    matlab.ui.control.EditField
        Label_7                matlab.ui.control.Label
        EditField_laser_path   matlab.ui.control.EditField
        Label_6                matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Button_15              matlab.ui.control.Button
        Button_14              matlab.ui.control.Button
        EditField_yaml_path    matlab.ui.control.EditField
        Label_5                matlab.ui.control.Label
        EditField_result_path  matlab.ui.control.EditField
        Label_4                matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        FIlter                 matlab.ui.control.CheckBox
        Fixed_lagCheckBox      matlab.ui.control.CheckBox
        RTSCheckBox            matlab.ui.control.CheckBox
        GridLayout4_3          matlab.ui.container.GridLayout
        Button_13              matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        NEDButton_2            matlab.ui.control.Button
        NEDButton              matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        GridLayout4_2          matlab.ui.container.GridLayout
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        D2R double = 0.017453292519943;
        gins_data double
        imu_err double
        imu_err_rts double
        laser_data double
        load_result logical = false;
        load_yaml logical = false;
        navdata double
        navdata_rts
        options % 配置文件
        refpos double
        run_time double
        run_time_rts double
        std double
        std_rts double
        fixedlag_data double
        fixedlag_imu_err double
        fixedlag_std double
        % Description
        fixedlag_time % Description
    end
    
methods (Access = private)
               
        function readGinsPav(app, fname)
            fid2 = fopen(fname, 'r');
            fread(fid2, 256, 'double');
            [data, ~] = fread(fid2, [17,inf], 'double');
            data = data';
            data = data(:,2:11);
            if mean(diff(data(:,1))) < 0
                data = flipud(data);
            end
            clear temp;
            fclose(fid2);
            app.gins_data = data;
        end

        function load_results(app)
            path = app.EditField_result_path.Value;
            fid = fopen(path + "/KF_GINS_Navresult.nav");
            [data, N] = fread(fid, 'double');
            fclose(fid);
            app.navdata = reshape(data, 11, N/11)';
            fid = fopen(path + "/KF_GINS_IMU_ERR.bin");
            [data, N] = fread(fid, 'double');
            fclose(fid);
            app.imu_err = reshape(data, 14, N/14)';
            if app.options.filtermode ==1
                w = 23;
            elseif app.options.filtermode ==2
                w = 17;
            end
            fid = fopen(path + "/KF_GINS_STD.bin");
            [data, N] = fread(fid, 'double');
            fclose(fid);
            app.std = reshape(data, w, N/w)';

            starttime = app.options.starttime;
            endtime = min([app.navdata(end,2), app.imu_err(end,2), app.std(end,2)]);
            app.navdata = app.navdata(app.navdata(:, 2)>=starttime & app.navdata(:, 2)<=endtime , :);
            app.imu_err = app.imu_err(app.imu_err(:, 2)>=starttime & app.imu_err(:, 2)<=endtime , :);
            app.std = app.std(app.std(:, 2)>=starttime & app.std(:, 2)<=endtime , :);
            app.run_time = app.navdata(:, 2) - starttime;

            if app.options.userts
                fid = fopen(path + "/KF_GINS_SM_Navresult.nav");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.navdata_rts = reshape(data, 11, N/11)';
                fid = fopen(path + "/KF_GINS_SM_IMU_ERR.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.imu_err_rts = reshape(data, 14, N/14)';
                fid = fopen(path + "/KF_GINS_SM_STD.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.std_rts = reshape(data, w, N/w)';
                endtime = min([app.navdata_rts(end,2), app.imu_err_rts(end,2), app.std_rts(end,2)]);
                app.navdata_rts = app.navdata_rts(app.navdata_rts(:, 2)>=starttime & app.navdata_rts(:, 2)<=endtime , :);
                app.imu_err_rts = app.imu_err_rts(app.imu_err_rts(:, 2)>=starttime & app.imu_err_rts(:, 2)<=endtime , :);
                app.std_rts = app.std_rts(app.std_rts(:, 2)>=starttime & app.std_rts(:, 2)<=endtime , :);

                app.run_time_rts = app.navdata_rts(:, 2) - starttime;
            end

            if app.options.usefixedlag
                fid = fopen(path + "/KF_GINS_FL_Navresult.nav");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.fixedlag_data = reshape(data, 11, N/11)';
                fid = fopen(path + "/KF_GINS_FL_IMU_ERR.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.fixedlag_imu_err = reshape(data, 14, N/14)';
                fid = fopen(path + "/KF_GINS_FL_STD.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.fixedlag_std = reshape(data, w, N/w)';
                endtime = min([app.fixedlag_data(end,2), app.fixedlag_imu_err(end,2), app.fixedlag_std(end,2)]);
                app.fixedlag_data = app.fixedlag_data(app.fixedlag_data(:, 2)>=starttime & app.fixedlag_data(:, 2)<=endtime , :);
                app.fixedlag_imu_err = app.fixedlag_imu_err(app.fixedlag_imu_err(:, 2)>=starttime & app.fixedlag_imu_err(:, 2)<=endtime , :);
                app.fixedlag_std = app.fixedlag_std(app.fixedlag_std(:, 2)>=starttime & app.fixedlag_std(:, 2)<=endtime , :);

                app.fixedlag_time = app.fixedlag_data(:, 2) - starttime;
            end

            app.refpos = cell2mat(app.options.refpos);
            app.refpos(1:2) = deg2rad(app.refpos(1:2));
            lever = [0;0;0];
            if app.options.isbacklever
                 lever = DRi(app.refpos) * Euler2C(deg2rad(cell2mat(app.options.initatt))) * cell2mat(app.options.antlever);
            end
            app.refpos = app.refpos + lever;
            if ~app.options.userts
                app.RTSCheckBox.Value = false;
                app.RTSCheckBox.Enable = false;
            end

            if ~app.options.usefixedlag
                app.Fixed_lagCheckBox.Value = false;
                app.Fixed_lagCheckBox.Enable = false;
            end

            app.set_button_state(true);
            app.Label_Info.Text = "已导入结果文件！";
        end
        
        function set_button_state(app, state)
            app.Button.Enable = state;
            app.Button_2.Enable = state;
            app.Button_3.Enable = state;
            app.Button_4.Enable = state;

            app.Button_5.Enable = state;
            app.Button_6.Enable = state;
            app.Button_7.Enable = state;
            app.Button_8.Enable = state;

            app.NEDButton.Enable = state;
            app.NEDButton_2.Enable = state;
            app.Button_9.Enable = state;
            app.Button_10.Enable = state;
            app.Button_11.Enable = state;
            app.Button_12.Enable = state;
            app.Button_13.Enable = state;
            
        end
        
        function  compare_laser(app, blh, time, str)
            blh(:, 1) = deg2rad(blh(:, 1));
            blh(:, 2) = deg2rad(blh(:, 2));
            ref_pos = app.refpos';

            [rm, rn] = getRmRn(ref_pos(1));
            h = ref_pos(3);
            DR = diag([rm + h, (rn + h)*cos(ref_pos(1)), -1]);
            head = deg2rad(cell2mat(app.options.initatt));
            head = head(3);
            C = [cos(head), -sin(head), 0; sin(head), cos(head), 0; 0, 0, 1];

            % blh to ned
            pos = zeros(size(blh));
            for i = 1:size(pos, 1)
                delta_blh = blh(i, :) - ref_pos;
                delta_pos = C * DR * delta_blh';
                pos(i, :) = delta_pos';
            end
            pos(:, 3) = -pos(:, 3);

            lasertime = app.laser_data(:, 1) - app.options.starttime;
            laser_disp =  app.laser_data(:, 2:end) -  app.laser_data(1, 2:end);

            if ~app.CheckBox.Value && ~app.CheckBox_2.Value && ~app.CheckBox_3.Value
                % 弹出警告对话框
                warndlg('未选择激光对比轴向！', '警告');
                return
            elseif app.CheckBox.Value && ~app.CheckBox_2.Value && ~app.CheckBox_3.Value
                laserdata = laser_disp(:, 1);
                data = pos(:, 1);
                ylabels = ["North[mm]"];
            elseif ~app.CheckBox.Value && app.CheckBox_2.Value && ~app.CheckBox_3.Value
                laserdata = laser_disp(:, 2);
                data = pos(:, 2);
                ylabels = ["East[mm]"];
            elseif ~app.CheckBox.Value && ~app.CheckBox_2.Value && app.CheckBox_3.Value
                laserdata = laser_disp(:, 3);
                data = pos(:, 3);
                ylabels = ["Height[mm]"];
            elseif app.CheckBox.Value && app.CheckBox_2.Value && ~app.CheckBox_3.Value
                laserdata = laser_disp(:, 1:2);
                data = pos(:, 1:2);
                ylabels = ["North[mm]", "East[mm]"];
            elseif app.CheckBox.Value && ~app.CheckBox_2.Value && app.CheckBox_3.Value
                laserdata = laser_disp(:, [1, 3]);
                data = pos(:, [1, 3]);
                ylabels = ["North[mm]", "Height[mm]"];
            elseif ~app.CheckBox.Value && app.CheckBox_2.Value && app.CheckBox_3.Value
                laserdata = laser_disp(:, 2:3);
                data = pos(:, 2:3);
                ylabels = ["East[mm]", "Height[mm]"];
            else
                if width(data_disp) < 3
                    % 弹出警告对话框
                    warndlg('激光数据列数错误！', '警告');
                    return
                end
                    data = pos;
                    laserdata = laser_disp;
                    ylabels = ["North[mm]", "East[mm]", "Height[mm]"];
            end
            figure("Name",str)
            subs=[];
            N = width(data);
            for i = 1:N
                sub = subplot(N, 1, i);
                subs=[subs, sub];
                hold on
                plot(lasertime, laserdata(:, i), "LineWidth",2,"DisplayName","Laser");
                plot(time, data(:, i) * 1e3, "LineWidth",2,"DisplayName","KF-GINS");
                ylabel(ylabels(i))
                grid on
                legend
                hold off
                set(gca, 'FontSize', 20)
            end
            xlabel("Time[s]")
            linkaxes(subs, 'x');
        end
        
        function compare_GINS(app, nav, time, str)
            pva_time = app.gins_data(:, 1) - app.options.starttime;

            if app.CheckBox_4.Enable

                res_blh = nav(:, 3:5);
                res_blh(:, 1) = deg2rad(res_blh(:, 1));
                res_blh(:, 2) = deg2rad(res_blh(:, 2));
                pva_blh = app.gins_data(:, 2:4);
                pva_blh(:, 1) = deg2rad(pva_blh(:, 1));
                pva_blh(:, 2) = deg2rad(pva_blh(:, 2));
                ref_pos = app.refpos;

                [rm, rn] = getRmRn(ref_pos(1));
                h = ref_pos(3);
                DR = diag([rm + h, (rn + h)*cos(ref_pos(1)), -1]);
                % blh to ned
                res_pos = zeros(size(res_blh));
                pva_pos = zeros(size(pva_blh));
                for i = 1:size(res_pos, 1)
                    delta_blh = res_blh(i, :) - ref_pos';
                    delta_pos = DR * delta_blh';
                    res_pos(i, :) = delta_pos';
                end
                for i = 1:size(pva_pos, 1)
                    if app.options.isbacklever
                        pva_blh(i, :) = pva_blh(i, :) +  (DRi(pva_blh(i, :)) * Euler2C(deg2rad(app.gins_data(i, 5:7))) * cell2mat(app.options.antlever))';
                    end
                    delta_blh = pva_blh(i, :) - ref_pos';
                    delta_pos = DR * delta_blh';
                    pva_pos(i, :) = delta_pos';
                end

                figure("Name", "Pos GINS - ref" + str);
                sub1 = subplot(3, 1, 1);
                hold on
                plot(time, res_pos(:, 1) * 1e3, "LineWidth", 2);
                plot(pva_time, pva_pos(:, 1) * 1e3, "LineWidth", 2);
                legend('KF-GINS', 'GINS');
                ylabel('North[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub2 = subplot(3,1,2);
                plot(time, res_pos(:, 2) * 1e3, "LineWidth", 2);
                hold on
                plot(pva_time, pva_pos(:, 2) * 1e3, "LineWidth", 2);
                legend('KF-GINS', 'GINS');
                ylabel('East[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3,1,3);
                plot(time, res_pos(:, 3) * 1e3, "LineWidth", 2);
                hold on
                plot(pva_time, pva_pos(:, 3) * 1e3, "LineWidth", 2);
                xlabel('Time[s]');
                legend('KF-GINS', 'GINS');
                ylabel('Height[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x')
            end

            if app.CheckBox_5.Value
                % velocity
                figure("Name", "Vel GINS - Ref" + str)
                sub1 = subplot(3, 1, 1);
                plot(time, nav(:, 6), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 8), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('North Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)
                legend('KF-GINS', 'GINS');

                sub2 = subplot(3, 1, 2);
                plot(time, nav(:, 7), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 9), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('East Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3, 1, 3);
                plot(time, nav(:, 8), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 10), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                xlabel('Time[s]');
                ylabel('Down Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x');
            end

            if app.CheckBox_6.Value
                % attitude
                figure("Name", "Att GINS - Ref" + str)
                sub1 = subplot(3, 1, 1);
                plot(time, nav(:, 9), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 5), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('Roll [deg]');
                grid("on");
                set(gca, 'FontSize', 20)
                legend('KF-GINS', 'GINS');

                sub2 = subplot(3, 1, 2);
                plot(time, nav(:, 10), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 6), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('Pitch [deg]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3, 1, 3);
                plot(time, nav(:, 11), "LineWidth", 2);
                hold on
                plot(pva_time, app.gins_data(:, 7), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                xlabel('Time[s]');
                ylabel('Yaw [deg]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x');
            end
        end

        function set_status_text(app, str)
            app.Label_Info.Text = str;
        end
        
        function export_all_figures(app)
            % 选择导出文件夹
            exportDir = uigetdir(pwd, '选择导出文件夹');
            if exportDir == 0
                app.set_status_text('用户取消了操作');
                return;
            end

            % 选择导出格式
            [indx, tf] = listdlg('PromptString', '选择导出格式', ...
                'SelectionMode', 'single', ...
                'ListString', {'fig', 'png'}, ...
                'InitialValue', 1);
            if ~tf
                app.set_status_text('用户取消了操作');
                return;
            end
            formats = {'fig', 'png'};
            exportFormat = formats{indx};

            % 获取所有 figure，排除 app.UIFigure
            figHandles = findall(0, 'Type', 'figure');
            figHandles = figHandles(~strcmp(get(figHandles, 'Name'), "GINS-Plot"));
            if isempty(figHandles)
                app.set_status_text('没有找到可导出的 figure');
                return;
            end

            % 遍历导出
            total = length(figHandles);
            for i = 1:total
                fig = figHandles(i);

                % 获取 figure 名称
                figName = get(fig, 'Name');
                if isempty(figName)
                    figName = sprintf('Figure_%d', fig.Number);
                end
                figName = regexprep(figName, '[\/:*?"<>|]', '_'); % 替换非法字符

                % 检查是否重名
                fullPath = fullfile(exportDir, [figName, '.', exportFormat]);
                count = 1;
                while isfile(fullPath)
                    fullPath = fullfile(exportDir, sprintf('%s_%d.%s', figName, count, exportFormat));
                    count = count + 1;
                end

                % 保存
                switch exportFormat
                    case 'fig'
                        savefig(fig, fullPath);
                    case 'png'
                        exportgraphics(fig, fullPath, 'Resolution', 600); % 原尺寸 600 DPI
                end

                % 在 App 状态栏显示进度
                app.set_status_text(sprintf('已导出 %d/%d: %s', i, total, fullPath));
                drawnow; % 确保 UI 更新
            end

            app.set_status_text('所有 figure 已导出完成。');
        end


end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("icons")
            app.UIFigure.Name = "GINS-Plot";
            app.UIFigure.Icon = "icons\icon.png";
            addpath("functions")
            app.set_button_state(false);
            app.Button_18.Enable = false;
            app.Button_19.Enable = false;
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            close all;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % 打开文件夹选择对话框
                folder_path = uigetdir('', '选择结果文件夹');

                % 如果用户选择了文件夹（不是点击取消）
                if folder_path ~= 0
                    % 设置 EditField_result_path 的文本为所选文件夹路径
                    app.EditField_result_path.Value = folder_path;
                end

                app.load_result = true;

                if app.load_yaml
                    app.load_results();
                end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 打开文件选择对话框，只显示 .yaml 文件
            [file, path] = uigetfile({'*.yaml', 'YAML Files (*.yaml)'}, '选择 YAML 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置 EditField_result_path 的文本为所选文件路径
            app.EditField_yaml_path.Value = fullpath;
            app.options =  yaml.loadFile(fullpath);

            app.load_yaml = true;

            if app.load_result
                app.load_results;
            end
        end

        % Value changed function: EditField_result_path
        function EditField_result_pathValueChanged(app, event)
            app.load_result = true;

            if app.load_yaml
                app.load_results();
            end
        end

        % Value changed function: EditField_yaml_path
        function EditField_yaml_pathValueChanged(app, event)
            value = app.EditField_yaml_path.Value;
            app.options =  yaml.loadFile(value);

            app.load_yaml = true;

            if app.load_result
                app.load_results;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            str_ = "Trajectory";
            if app.options.userts && app.RTSCheckBox.Value
                blh = app.navdata_rts(:, 3:5);
                str = str_ + "_RTS";
                plot_traj(blh, str, app.refpos');
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                blh = app.fixedlag_data(:, 3:5);
                str = str_ + "_Fixed-lag";
                plot_traj(blh, str, app.refpos');
            end
            if app.FIlter.Value
                blh = app.navdata(:, 3:5);
                str = str_;
                plot_traj(blh, str, app.refpos');
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            str_ = "Height";
            if app.options.userts && app.RTSCheckBox.Value
                height = app.navdata_rts(:, 5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_height(time, height, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                height = app.fixedlag_data(:, 5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_height(time, height, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                height = app.navdata(:, 5);
                str = str_;
                plot_height(time, height, str);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            str_ = "Velocity";
            if app.options.userts && app.RTSCheckBox.Value
                vel = app.navdata_rts(:, 6:8);
                str = str_ + "_RTS";
                time = app.run_time_rts;
                plot_vel(time, vel, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                vel = app.fixedlag_data(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_vel(time, vel, str);
            end
            if app.FIlter.Value
                vel = app.navdata(:, 6:8);
                time = app.run_time;
                str = str_;
                plot_vel(time, vel, str);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            str_ = "Attitude";
            if app.options.userts && app.RTSCheckBox.Value
                att = app.navdata_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_att(time, att, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                att = app.fixedlag_data(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_att(time, att, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                att = app.navdata(:, 9:11);
                str = str_;
                plot_att(time, att, str);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            str_ = "GyroBias";
            if app.options.userts && app.RTSCheckBox.Value
                gb = app.imu_err_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyro_bias(time, gb, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gb = app.fixedlag_imu_err(:, 3:5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyro_bias(time, gb, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gb = app.imu_err(:, 3:5);
                str = str_;
                plot_gyro_bias(time, gb, str);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            str_ = "AccelBias";
            if app.options.userts && app.RTSCheckBox.Value
                ab = app.imu_err_rts(:, 6:8);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_acc_bias(time, ab, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                ab = app.fixedlag_imu_err(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_acc_bias(time, ab, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                ab = app.imu_err(:, 6:8);
                str = str_;
                plot_acc_bias(time, ab, str);
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str_ = "GyroScale";
            if app.options.userts && app.RTSCheckBox.Value
                gs = app.imu_err_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyro_scale(time, gs, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gs = app.fixedlag_imu_err(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyro_scale(time, gs, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gs = app.imu_err(:, 9:11);
                str = str_;
                plot_gyro_scale(time, gs, str);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str_ = "AccelScale";
            if app.options.userts && app.RTSCheckBox.Value
                as = app.imu_err_rts(:, 12:14);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_acc_scale(time, as, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                as = app.fixedlag_imu_err(:, 12:14);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_acc_scale(time, as, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                as = app.imu_err(:, 12:14);
                str = str_;
                plot_acc_scale(time, as, str);
            end
            plot_acc_scale(time, as, str);
        end

        % Button pushed function: NEDButton
        function NEDButtonPushed(app, event)
            str_ = "Pos STD";
            if app.options.userts && app.RTSCheckBox.Value
                posstd = app.std_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_pos_std(time, posstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                posstd = app.fixedlag_std(:, 3:5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_pos_std(time, posstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                posstd = app.std(:, 3:5);
                str = str_;
                plot_pos_std(time, posstd, str);
            end
        end

        % Button pushed function: NEDButton_2
        function NEDButton_2Pushed(app, event)
            str_ = "Vel STD";
            if app.options.userts && app.RTSCheckBox.Value
                velstd = app.std_rts(:, 6:8);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_vel_std(time, velstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                velstd = app.fixedlag_std(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_vel_std(time, velstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                velstd = app.std(:, 6:8);
                str = str_;
                plot_vel_std(time, velstd, str);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            str_ = "Att STD";
            if app.options.userts && app.RTSCheckBox.Value
                attstd = app.std_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_att_std(time, attstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                attstd = app.fixedlag_std(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_att_std(time, attstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                attstd = app.std(:, 9:11);
                str = str_;
                plot_att_std(time, attstd, str);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            str_ = "Gyro Bias STD";
            if app.options.userts && app.RTSCheckBox.Value
                gbstd = app.std_rts(:, 12:14);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyrobias_std(time, gbstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gbstd = app.fixedlag_std(:, 12:14);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyrobias_std(time, gbstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gbstd = app.std(:, 12:14);
                str = str_;
                plot_gyrobias_std(time, gbstd, str);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            str_ = "Acc Bias STD";
            if app.options.userts && app.RTSCheckBox.Value
                abstd = app.std_rts(:, 15:17);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_accbias_std(time, abstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                abstd = app.fixedlag_std(:, 15:17);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_accbias_std(time, abstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                abstd = app.std(:, 15:17);
                str = str_;
                plot_accbias_std(time, abstd, str);
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif app.options.filtermode == 1
                str_ = "GyroScale STD";
                if app.options.userts && app.RTSCheckBox.Value
                    gsstd = app.std_rts(:, 18:20);
                    time = app.run_time_rts;
                    str = str_ + "_RTS";
                    plot_gyroscale_std(time, gsstd, str);
                end
                if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                    gsstd = app.fixedlag_std(:, 18:20);
                    time = app.fixedlag_time;
                    str = str_ + "_Fixed-lag";
                    plot_gyroscale_std(time, gsstd, str);
                end
                if app.FIlter.Value
                    time = app.run_time;
                    gsstd = app.std(:, 18:20);
                    str = str_;
                    plot_gyroscale_std(time, gsstd, str);
                end
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif app.options.filtermode ==1
                str_ = "AccelScale STD";
                if app.options.userts && app.RTSCheckBox.Value
                    asstd = app.std_rts(:, 21:23);
                    time = app.run_time_rts;
                    str = str_ + "_RTS";
                    plot_gyroscale_std(time, asstd, str);
                end
                if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                    asstd = app.fixedlag_std(:, 21:23);
                    time = app.fixedlag_time;
                    str = str_ + "_Fixed-lag";
                    plot_gyroscale_std(time, asstd, str);
                end
                if app.FIlter.Value
                    time = app.run_time;
                    asstd = app.std(:, 21:23);
                    str = str_;
                    plot_gyroscale_std(time, asstd, str);
                end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            [file, path] = uigetfile({'*.txt', 'Laser Files (*.txt)'}, '选择 Txt 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            app.EditField_laser_path.Value = fullpath;
            app.laser_data = importdata(fullpath);

            if app.load_result && app.load_yaml
                app.laser_data = app.laser_data(app.laser_data(:, 1)>app.options.starttime, :);
                app.Button_18.Enable =true;
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            % position
            str_ = "Laser - Ref";
            if app.options.userts && app.RTSCheckBox.Value
                blh = app.navdata_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                app.compare_laser(blh, time, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                blh = app.fixedlag_data(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_Fixed-lag";
                app.compare_laser(blh, time, str);
            end
            if app.FIlter.Value
                blh = app.navdata(:, 3:5);
                time = app.run_time;
                str = str_;
                app.compare_laser(blh, time, str);
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            [file, path] = uigetfile({'*.pva', 'GINS Files (*.pva)'}, '选择 PVA 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            app.EditField_gins_path.Value = fullpath;
            app.readGinsPav(fullpath);

            if app.load_result && app.load_yaml
                app.gins_data = app.gins_data(app.gins_data(:, 1)>app.options.starttime, :);
                app.Button_19.Enable =true;
            end
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            str_ = "";
            if app.options.userts && app.RTSCheckBox.Value
                time = app.run_time_rts;
                nav = app.navdata_rts;
                str = str_ + "_RTS";
                app.compare_GINS(nav, time, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                nav = app.fixedlag_data;
                time = app.run_time_rts;
                str = str_ + "_Fixed-lag";
                app.compare_GINS(nav, time, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                nav = app.navdata;
                str = str_;
                app.compare_GINS(nav, time, str);
            end
            
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            app.set_button_state(false);
            app.EditField_gins_path.Value = "";
            app.EditField_laser_path.Value = "";
            app.EditField_result_path.Value = "";
            app.EditField_yaml_path.Value = "";
            app.load_result = false;
            app.load_yaml = false;
            app.Button_18.Enable = false;
            app.Button_19.Enable = false;

            app.RTSCheckBox.Value = false;
            app.RTSCheckBox.Enable = true;
            app.Fixed_lagCheckBox.Value = false;
            app.Fixed_lagCheckBox.Enable = true;

            app.Label_Info.Text = "";
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            app.options =  yaml.loadFile(app.EditField_yaml_path.Value);
            app.load_results;
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            app.export_all_figures;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'关闭所有图片'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', '关闭.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'清除文件'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icons', '清除文件按钮.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'重新导入文件'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icons', '读取文件所有文本内容.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'保存图片'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icons', '保存图片-copy.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '   绘图选项';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '轨迹';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '导航状态';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '高程';
            app.Button_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '速度';
            app.Button_4 = uibutton(app.GridLayout4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '姿态';
            app.GridLayout4_2 = uigridlayout(app.GridLayout3);
            app.Label_2 = uilabel(app.GridLayout4_2);
            app.Label_2.Text = '惯导误差';
            app.Button_5 = uibutton(app.GridLayout4_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '陀螺零偏';
            app.Button_6 = uibutton(app.GridLayout4_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '加速度计零偏';
            app.Button_7 = uibutton(app.GridLayout4_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '陀螺比例因子';
            app.Button_8 = uibutton(app.GridLayout4_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '加速度计比例因子';
            app.GridLayout4_3 = uigridlayout(app.GridLayout3);
            app.Label_3 = uilabel(app.GridLayout4_3);
            app.Label_3.Text = '标准差';
            app.NEDButton = uibutton(app.GridLayout4_3, 'push');
            app.NEDButton.ButtonPushedFcn = createCallbackFcn(app, @NEDButtonPushed, true);
            app.NEDButton.Text = 'NED位置';
            app.NEDButton_2 = uibutton(app.GridLayout4_3, 'push');
            app.NEDButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEDButton_2Pushed, true);
            app.NEDButton_2.Text = 'NED速度';
            app.Button_9 = uibutton(app.GridLayout4_3, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '姿态';
            app.Button_10 = uibutton(app.GridLayout4_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '陀螺零偏';
            app.Button_11 = uibutton(app.GridLayout4_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '加速度计零偏';
            app.Button_12 = uibutton(app.GridLayout4_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '陀螺比例因子';
            app.Button_13 = uibutton(app.GridLayout4_3, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '加速度计比例因子';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.RTSCheckBox = uicheckbox(app.GridLayout6);
            app.RTSCheckBox.Text = 'RTS';
            app.Fixed_lagCheckBox = uicheckbox(app.GridLayout6);
            app.Fixed_lagCheckBox.Text = 'Fixed_lag';
            app.FIlter = uicheckbox(app.GridLayout6);
            app.FIlter.Text = 'Filter';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '   文件路径';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '结果文件路径';
            app.EditField_result_path = uieditfield(app.GridLayout2, 'text');
            app.EditField_result_path.ValueChangedFcn = createCallbackFcn(app, @EditField_result_pathValueChanged, true);
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '配置文件路径';
            app.EditField_yaml_path = uieditfield(app.GridLayout2, 'text');
            app.EditField_yaml_path.ValueChangedFcn = createCallbackFcn(app, @EditField_yaml_pathValueChanged, true);
            app.Button_14 = uibutton(app.GridLayout2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_15.Text = '';
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '   参考值';
            app.GridLayout2_2 = uigridlayout(app.Panel_3);
            app.Label_6 = uilabel(app.GridLayout2_2);
            app.Label_6.Text = '激光参考值路径';
            app.EditField_laser_path = uieditfield(app.GridLayout2_2, 'text');
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Text = 'GINS参考值路径';
            app.EditField_gins_path = uieditfield(app.GridLayout2_2, 'text');
            app.Button_16 = uibutton(app.GridLayout2_2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout2_2, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_17.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2_2);
            app.CheckBox = uicheckbox(app.GridLayout5);
            app.CheckBox.Text = '前向';
            app.CheckBox_2 = uicheckbox(app.GridLayout5);
            app.CheckBox_2.Text = '侧向';
            app.CheckBox_3 = uicheckbox(app.GridLayout5);
            app.CheckBox_3.Text = '垂向';
            app.Button_18 = uibutton(app.GridLayout2_2, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '绘制';
            app.Button_19 = uibutton(app.GridLayout2_2, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '绘制';
            app.GridLayout5_2 = uigridlayout(app.GridLayout2_2);
            app.CheckBox_4 = uicheckbox(app.GridLayout5_2);
            app.CheckBox_4.Text = '位置';
            app.CheckBox_5 = uicheckbox(app.GridLayout5_2);
            app.CheckBox_5.Text = '速度';
            app.CheckBox_6 = uicheckbox(app.GridLayout5_2);
            app.CheckBox_6.Text = '姿态';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Label_Info = uilabel(app.Panel_4);
            app.Label_Info.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GINSPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end